classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        VariableBlockingSimulationLabel_2  matlab.ui.control.Label
        VariableBlockingSimulationLabel  matlab.ui.control.Label
        rickkyzzz2023Label              matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        x4DCTCoefficientsSpinner        matlab.ui.control.Spinner
        x4DCTCoefficientsSpinnerLabel   matlab.ui.control.Label
        x16DCTCoefficientsSpinner       matlab.ui.control.Spinner
        x16DCTCoefficientsSpinnerLabel  matlab.ui.control.Label
        x8DCTCoefficientsSpinner        matlab.ui.control.Spinner
        x8DCTCoefficientsLabel          matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            I = imread('peppers.tif');
            I = im2double(I);
            imshow(I, 'parent', app.UIAxes);
            
            mask = [1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
                    1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0
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                    0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0]
                    mask4 = circshift(mask,[app.x4DCTCoefficientsSpinner.Value app.x4DCTCoefficientsSpinner.Value])
                    mask8 = circshift(mask,[app.x8DCTCoefficientsSpinner.Value app.x8DCTCoefficientsSpinner.Value])
                    mask16 = circshift(mask,[app.x16DCTCoefficientsSpinner.Value app.x16DCTCoefficientsSpinner.Value])
            
            %DCT Block 4x4
            T1 = dctmtx(4);
            dct1 = @(block_struct) T1 * block_struct.data * T1';
            B_1 = blockproc(I,[4 4],dct1);
            
            B1 = blockproc(B_1,[4 4],@(block_struct) mask4(33:36,33:36) .* block_struct.data);

            invdct1 = @(block_struct) T1' * block_struct.data * T1;
            I1 = blockproc(B1,[4 4],invdct1);
            
            imshow(I1, 'parent', app.UIAxes_2)
                        
            %DCT Block 8x8
            T2 = dctmtx(8);
            dct2 = @(block_struct) T2 * block_struct.data * T2';
            B_2 = blockproc(I,[8 8],dct2);
            
            B2 = blockproc(B_2,[8 8],@(block_struct) mask8(33:40,33:40) .* block_struct.data);

            invdct2 = @(block_struct) T2' * block_struct.data * T2;
            I2 = blockproc(B2,[8 8],invdct2);
                        
            imshow(I2, 'parent', app.UIAxes_3)
            
            %DCT Block 16x16
            T3 = dctmtx(16);
            dct3 = @(block_struct) T3 * block_struct.data * T3';
            B_3 = blockproc(I,[16 16],dct3);
            
            B3 = blockproc(B_3,[16 16],@(block_struct) mask16(33:48,33:48) .* block_struct.data);

            invdct3 = @(block_struct) T3' * block_struct.data * T3;
            I3 = blockproc(B3,[16 16],invdct3);
                        
            imshow(I3, 'parent', app.UIAxes_4)
            
        end

        % Value changed function: x8DCTCoefficientsSpinner
        function x8DCTCoefficientsSpinnerValueChanged(app, event)
            value8 = app.x8DCTCoefficientsSpinner.Value;
        end

        % Value changed function: x16DCTCoefficientsSpinner
        function x16DCTCoefficientsSpinnerValueChanged(app, event)
            value16 = app.x16DCTCoefficientsSpinner.Value;
            
        end

        % Value changed function: x4DCTCoefficientsSpinner
        function x4DCTCoefficientsSpinnerValueChanged(app, event)
            value4 = app.x4DCTCoefficientsSpinner.Value;
            
        end

        % Value changing function: x4DCTCoefficientsSpinner
        function x4DCTCoefficientsSpinnerValueChanging(app, event)
            changingValue4 = event.Value;
            
        end

        % Value changing function: x8DCTCoefficientsSpinner
        function x8DCTCoefficientsSpinnerValueChanging(app, event)
            changingValue8 = event.Value;
            
        end

        % Value changing function: x16DCTCoefficientsSpinner
        function x16DCTCoefficientsSpinnerValueChanging(app, event)
            changingValue16 = event.Value;
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            cla(app.UIAxes, 'reset')
            cla(app.UIAxes_2, 'reset') 
            cla(app.UIAxes_3, 'reset') 
            cla(app.UIAxes_4, 'reset')
            title(app.UIAxes,'Gambar Asli')
            title(app.UIAxes_2,'Hasil Variable Sub-block 4x4')
            title(app.UIAxes_3,'Hasil Variable Sub-block 8x8')
            title(app.UIAxes_4,'Hasil Variable Sub-block 16x16')
            app.x4DCTCoefficientsSpinner.Value = 0;
            app.x8DCTCoefficientsSpinner.Value = 0;
            app.x16DCTCoefficientsSpinner.Value = 0;
        end

        % Callback function
        function MenuUtamaButtonPushed(app, event)
            start
            app.UIFigure.Visible = 'off';
        end

        % Callback function
        function PetunjukButtonPushed(app, event)
            petunjuk
            app.UIFigure.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {706, 706};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {167, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.rickkyzzz2023Label = uilabel(app.LeftPanel);
            app.rickkyzzz2023Label.Text = '© rickkyzzz 2023';
            app.VariableBlockingSimulationLabel = uilabel(app.LeftPanel);
            app.VariableBlockingSimulationLabel.Text = {'Variable'; 'Blocking'; 'Simulation'};
            app.VariableBlockingSimulationLabel_2 = uilabel(app.LeftPanel);
            app.VariableBlockingSimulationLabel_2.Text = {'This program is'; 'capable of simulating'; '4x4, 8x8, and 16x16'; 'DCT blocking. It'; 'also allows'; 'customization of'; 'DCT coefficients.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Variable Block 4x4')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Variable Block 8x8')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Variable Block 16x16')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.x8DCTCoefficientsLabel = uilabel(app.RightPanel);
            app.x8DCTCoefficientsLabel.Text = {'8x8 DCT'; 'Coefficients'};
            app.x8DCTCoefficientsSpinner = uispinner(app.RightPanel);
            app.x8DCTCoefficientsSpinner.ValueChangingFcn = createCallbackFcn(app, @x8DCTCoefficientsSpinnerValueChanging, true);
            app.x8DCTCoefficientsSpinner.Limits = [0 8];
            app.x8DCTCoefficientsSpinner.ValueChangedFcn = createCallbackFcn(app, @x8DCTCoefficientsSpinnerValueChanged, true);
            app.x16DCTCoefficientsSpinnerLabel = uilabel(app.RightPanel);
            app.x16DCTCoefficientsSpinnerLabel.Text = {'16x16 DCT'; 'Coefficients'};
            app.x16DCTCoefficientsSpinner = uispinner(app.RightPanel);
            app.x16DCTCoefficientsSpinner.ValueChangingFcn = createCallbackFcn(app, @x16DCTCoefficientsSpinnerValueChanging, true);
            app.x16DCTCoefficientsSpinner.Limits = [0 16];
            app.x16DCTCoefficientsSpinner.ValueChangedFcn = createCallbackFcn(app, @x16DCTCoefficientsSpinnerValueChanged, true);
            app.x4DCTCoefficientsSpinnerLabel = uilabel(app.RightPanel);
            app.x4DCTCoefficientsSpinnerLabel.Text = {'4x4 DCT'; 'Coefficients'};
            app.x4DCTCoefficientsSpinner = uispinner(app.RightPanel);
            app.x4DCTCoefficientsSpinner.ValueChangingFcn = createCallbackFcn(app, @x4DCTCoefficientsSpinnerValueChanging, true);
            app.x4DCTCoefficientsSpinner.Limits = [0 4];
            app.x4DCTCoefficientsSpinner.ValueChangedFcn = createCallbackFcn(app, @x4DCTCoefficientsSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end